classdef clean_app_en < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        RightPanel         matlab.ui.container.Panel
        framesliderSlider  matlab.ui.control.Slider
        Label              matlab.ui.control.Label
        Puase_button       matlab.ui.control.Button
        Start_button       matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        pause_flag = false;
        pause_activate = false;

        history_data = {};
        name_root = './cache_data/CD_';
        name_counter = 1;

        frame_counter = 1;

        slider_major_nums = 6;
        slider_minor_nums = 8;

        limit = 10;

        name_space_temp = {}; 

        max_speed_small = 0;
        emergency_flag = 0;

        observation_points = [];

        pause_activate_back = true;

        result_struct = [];

        start_status_flag = true;
    end
    
    methods (Access = private)
        % Initializer for all coefficients involved in the simulation
        function coefficient_initializer(app)
            %% simulation related coefficient initialization
            app.pause_flag = false;
            app.pause_activate = false;
    
            app.history_data = {};
            app.name_root = './cache_data/CD_';
            app.name_counter = 1;
    
            app.frame_counter = 1;
    
            app.slider_major_nums = 6;
            app.slider_minor_nums = 8;
    
            app.limit = 10;
    
            app.name_space_temp = {}; 
    
            app.max_speed_small = 0;
            app.emergency_flag = 0;
    
            app.observation_points = [];
    
            app.pause_activate_back = true;
    
            app.result_struct = [];

            %% slider UI related coefficient initialization 
            app.framesliderSlider.Value = app.frame_counter;
            app.framesliderSlider.Limits = [0, app.frame_counter];

            app.framesliderSlider.MajorTicks = [0, app.frame_counter];
            app.framesliderSlider.MajorTickLabels = {num2str(0), num2str(app.frame_counter)};

            app.framesliderSlider.MinorTicks = [0, app.frame_counter];

            %% visualization related coefficient initialization
            cla(app.UIAxes)
            app.UIAxes.Title = [];
            app.UIAxes.XLabel = [];
            app.UIAxes.YLabel = [];

            colorbar(app.UIAxes, 'off')
            

            app.Start_button.Text = "start";
            app.Puase_button.Text = "pause";
        end

        function update_silder(app)
            % update the slider related coefficients
            app.framesliderSlider.Limits = [0, app.frame_counter];
            app.framesliderSlider.Value = app.frame_counter;
            
            if app.frame_counter < app.slider_major_nums
                app.framesliderSlider.MajorTicks = linspace(0, app.frame_counter, app.frame_counter);
            else
                app.framesliderSlider.MajorTicks = linspace(0, app.frame_counter, app.slider_major_nums);
            end

            app.framesliderSlider.MajorTickLabels = {'a'};
            for i = 1 : length(app.framesliderSlider.MajorTicks)
                app.framesliderSlider.MajorTickLabels{i} = num2str(app.framesliderSlider.MajorTicks(i));
            end
            
            app.framesliderSlider.MinorTicks = [];
            if length(app.framesliderSlider.MajorTicks) >= 2
                for i = 2 : length(app.framesliderSlider.MajorTicks)
                    if app.framesliderSlider.MajorTicks(i) - app.framesliderSlider.MajorTicks(i - 1)> app.slider_minor_nums
                        app.framesliderSlider.MinorTicks = [app.framesliderSlider.MinorTicks, linspace(app.framesliderSlider.MajorTicks(i - 1), ...
                            app.framesliderSlider.MajorTicks(i), app.slider_minor_nums)];
                    else
                        app.framesliderSlider.MinorTicks = [app.framesliderSlider.MinorTicks, linspace(app.framesliderSlider.MajorTicks(i - 1), ...
                            app.framesliderSlider.MajorTicks(i), app.framesliderSlider.MajorTicks(i) - app.framesliderSlider.MajorTicks(i - 1))];
                    end
                end
            end
        end
        
        %% single step visualization function
        function visualize_road(app, time_step, road, observation_points, max_speed, emergency_flag)
            obs_colors = {'#6F4F67', '#8F302E', '#D27E64', '#5D977B'}; % observation point color map
            title(app.UIAxes, ['Time Step: ', int2str(time_step)]); % image title
            xlabel(app.UIAxes, 'X (m)'); % x label
            ylabel(app.UIAxes, 'Y (m)'); % y label 
            degree1 = 50; % road fragment 1 tilt angle
            degree2 = 35; % road fragment 2 tilt angle
        
            if emergency_flag==1
                txt = {'On'};
            else
                txt = {'Down'};
            end
            text(app.UIAxes, 3500,4200,txt);
         
            %% visualize the observation point
            for i = 1:length(observation_points)
                if observation_points(i) <= 2000
                    x1 = observation_points(i) * cosd(degree1);
                    y1 = 0;
        
                    x2 = observation_points(i) * cosd(degree1);
                    y2 = 4500;
                else
                    x1 = 2000 * cosd(degree1) + (observation_points(i) - 2000) * cosd(degree2);
                    y1 = 0;
        
                    x2 = 2000 * cosd(degree1) + (observation_points(i) - 2000) * cosd(degree2);
                    y2 = 4500;
                end
        
                % use square rectangle (15 in side length) to represent vehicle
                faces = [1, 2, 3, 4];
                vertices = [x1, y1; x1, y2; x2+15, y2; x2+15, y1];
                patch(app.UIAxes, 'faces', faces, 'vertices', vertices, 'EdgeColor', 'None', 'FaceColor', obs_colors{i}, 'FaceAlpha', 0.5);
            end
            
            %% road visualization
            for lane = (0:length(road))-0.5
                % visualization of road fragment 1
                plot(app.UIAxes, [0, 2000 * cosd(degree1)], -[lane *15 * 6, lane * 15 * 6 + 2000 * sind(degree1)] + [4500 4500], 'k-');
                % visualization of road fragment 2
                plot(app.UIAxes, [2000 * cosd(degree1), 2000 * cosd(degree1) + 3000 * cosd(degree2)], ...
                     -[lane * 15 * 6 + 2000 * sind(degree1), lane * 15 * 6 + 2000 * sind(degree1) + 3000 * sind(degree2)] + [4500 4500], 'k-');
            end 
            
            %% vehicle info calculation
            xs = [];
            ys = [];
            ad = [];
            for lane = 1:length(road)
                for i = 1:length(road{lane})
                    vehicle = road{lane}(i);
                    if vehicle.position <= 2000
                        x = vehicle.position * cosd(degree1);
                        y = 4500-((lane - 1) * 15 * 6 + vehicle.position * sind(degree1));
                    else
                        x = 2000 * cosd(degree1) + (vehicle.position - 2000) * cosd(degree2);
                        y = 4500-((lane - 1) * 15 * 6 + 2000 * sind(degree1) + (vehicle.position - 2000) * sind(degree2));
                    end
                    xs = [xs, x];
                    ys = [ys, y];

                    ad = [ad, 1]; 
                end
            end
            
            %% vehicle visualization
            % visualize the calculated vehicle data
            s = scatter(app.UIAxes, xs, ys, 35, linspace(0, max_speed, length(xs)), "filled",'Marker', "square"); 
            s.AlphaData = ad; % opacity settings
            s.MarkerFaceAlpha = 'flat';
        
            h = colorbar(app.UIAxes); % colorbar generation
            h.TickLabels = linspace(0, max_speed, length(h.TickLabels)); % colorbar stick setting

            % colorbar(app.UIAxes, 'Ticks',[])
            
            axis(app.UIAxes, [-15 3800 0 4600]); % visualization range setting

            if app.framesliderSlider.Value == app.framesliderSlider.Limits(2) && app.frame_counter - time_step == 0
                app.update_silder;

                app.frame_counter = app.frame_counter + 1;
            end
        end

        function pause_simulate(app)
            while 1
                pause(0.05)
                if app.pause_activate
                    break
                end
            end

            app.pause_flag = false;
            app.pause_activate = false;
        end
        
        function data_save(app, history_data)
            filename_temp = [app.name_root, num2str(app.name_counter, "%03d"), '.mat'];
            while exist(filename_temp, "file")
                app.name_counter = app.name_counter + 1;
                filename_temp = [app.name_root, num2str(app.name_counter, "%03d"), '.mat'];
            end
            app.name_space_temp{end+1} = filename_temp;
            save(filename_temp, "history_data"); 
        end

        function simulator_initializer(app)
            %% data loading and the initial process

            % load the example data as the system input
            load("Example_Data.mat")
            
            % calculate the data
            flow = data1{:, 2};% traffic flow 2nd column
            speed = data1{:, 3};speed2 = data2{:, 3};speed3 = data3{:, 3};speed4 = data4{:, 3};  % vehicle speed 3rd column
            density = data1{:, 4};density2 = data2{:, 4};density3 = data3{:, 4}; % density 4th column
           
            % data calculation
            speed1_avg = mean(speed);speed2_avg = mean(speed2);speed3_avg = mean(speed3);speed4_avg = mean(speed4);
            density1_avg = mean(density);density2_avg = mean(density2);density3_avg = mean(density3);
            
            %% parameter setting
            road_length = 5000;  % total road length
            num_lanes = 2;  % number of ordinary lanes
            emergency_lane = 1;  % number of emergency lanes
            total_lanes = num_lanes + emergency_lane;  % number of total lanes
            app.max_speed_small = 33;  % maximum speed limit for small vehicle
            max_speed_other = 27; % maximum speed limit for other vehicle
            app.emergency_flag = 0; % 1 for activate the emergency lanes 0 otherwise

            app.observation_points = [0, 1000, 2000, 5000];  % observation location
            caculate_points = [[900, 1100];
                               [1900,2100];
                               [4800,5000]];
            %% initial variable settings
            % vehicle structure entity 
            vehicles = struct('id', {}, 'type', {}, 'length', {}, 'max_speed', {}, 'speed', {}, 'position', {}, 'lane', {});
            
            % vehicle type structure
            vehicle_types = {
                'small', 4, app.max_speed_small;  % small vehicle 
                'medium', 6, max_speed_other;  % medimum vehicle
                'large', 8, max_speed_other  % large vehicle
            };
            
            % road initialization
            road = cell(total_lanes, 1);
            for lane = 1:total_lanes
                road{lane} = [];
            end
            
            % speed unit: m/s
            speed_list = [speed1_avg/3.6, speed2_avg/3.6, speed3_avg/3.6, speed4_avg/3.6];
            
            % put vehicle onto the lane
            num_vehicles_list = [round(density1_avg*1000), round(density2_avg*1000), round(density3_avg*3000)];
            total_num_vehicles = sum(num_vehicles_list);
            num_vehicles = total_num_vehicles;
            count = 1;
            for i = 1:3
                position_range = app.observation_points(i:i+1);
                for j = count:count+num_vehicles_list(i)
                    type_idx = randi(size(vehicle_types, 1));
                    vehicles(j).id = j;
                    vehicles(j).type = vehicle_types{type_idx, 1};
                    vehicles(j).length = vehicle_types{type_idx, 2};
                    vehicles(j).max_speed = vehicle_types{type_idx, 3};
                    vehicles(j).speed = speed_list(i);
                    vehicles(j).position = randi(position_range);
                    vehicles(j).lane = randi(num_lanes);
                    road{vehicles(j).lane} = [road{vehicles(j).lane}, vehicles(j)]; 
                end
                count = count + num_vehicles_list(i);
            end
            
            % lane as the index
            for lane = 1:num_lanes
                % put the vehicle as the 
                [~, idx] = sort([road{lane}.position]);
                road{lane} = road{lane}(idx);
            end
            
            num_trials = 3; % set the sampling times
            counts = zeros(1, num_trials); % initialize the result array
            
            app.result_struct = struct("num_trials", {num_trials},...
                "counts", {counts},...
                "vehicle_types", {vehicle_types},...
                "vehicles", {vehicles},...
                "road", {road},...
                "num_vehicles", {num_vehicles}, ...
                "total_lanes", {total_lanes}, ...
                "road_length", {road_length}, ...
                "caculate_points", {caculate_points}, ...
                "flow", {flow},...
                "data1_ori", {data1_ori},...
                "num_lanes", {num_lanes},...
                "step", {1});
        end
        
        function simulator(app)
            % single step simulation
            num_trials = app.result_struct.num_trials; counts = app.result_struct.counts;
            vehicle_types = app.result_struct.vehicle_types; vehicles = app.result_struct.vehicles;
            road = app.result_struct.road; num_vehicles = app.result_struct.num_vehicles;
            total_lanes = app.result_struct.total_lanes; road_length = app.result_struct.road_length;
            caculate_points = app.result_struct.caculate_points; step = app.result_struct.step;
            flow = app.result_struct.flow; data1_ori = app.result_struct.data1_ori;
            num_lanes = app.result_struct.num_lanes;

            if mod(step,3)==0  % generate data per 3 seconds 
                % dynamically generate a new vehicle according to traffic data
                num_new_vehicles = round(flow(step/3));
                remaining_count = num_new_vehicles;
                for i = 1:num_trials-1
                    counts(i) = randi([0, remaining_count]); 
                    remaining_count = remaining_count - counts(i);
                end
                counts(num_trials) = remaining_count;
            else
                if ~isempty(counts)
                    for i = 1:counts(mod(step,3))
                        type_idx = randi(size(vehicle_types, 1));
                        vehicles(i).id = i+num_vehicles;
                        vehicles(i).type = vehicle_types{type_idx, 1};
                        vehicles(i).length = vehicle_types{type_idx, 2};
                        vehicles(i).max_speed = vehicle_types{type_idx, 3};
                        vehicles(i).speed = data1_ori{:,3}(step)/3.6;
                        vehicles(i).position = randi([0, 100]);
                        vehicles(i).lane = randi(num_lanes);
                        road{vehicles(i).lane} = [road{vehicles(i).lane}, vehicles(i)]; 
                    end
                    num_vehicles = num_vehicles + counts(mod(step,3));
                end
            end
            avgv = 0; roudouble=0;
            for lane = 1:total_lanes
                if ~isempty(road{lane})
                    road = update_lane(road{lane}, lane, road, road_length, app.emergency_flag);
                    avgv = (mean([road{1}.speed]) + mean([road{2}.speed]))/2;
                    roudouble = (length(road{1})+length(road{2}))/5000;
                end
            end
        
            %% data saving in variables
            data = cell(3, length(app.observation_points));
            for i = 2:length(app.observation_points)
                if mod(step,3)==0
                    step_temp = 3;
                else
                    step_temp = mod(step,3);
                end
                data{step_temp, i} = record_data(road, caculate_points(i-1,:));
                q_ori{step,i} = length(data{step_temp, i});
                v_ori{step,i} = (mean(cell2mat(data{step_temp, i}(:,3))))*3.6;
                rou_ori{step,i} = (q_ori{step,i}/(v_ori{step,i}))*1000;
            end
            if mod(step,3)==0
                for j = 2:length(app.observation_points)
                    q_temp = 0; v_temp = 0;
                    for i=1:3
                        if ~isempty(data{i, j})
                            count = length(data{i, j});
                            q_temp = q_temp + count;
                            v_temp = v_temp + mean(cell2mat(data{i, j}(:,3)));
                            data{i,j} = [];
                        end
                    end
                    q{step/3,j} = q_temp;
                    v{step/3,j} = (v_temp/3)*3.6;
                    rou{step/3,j} = (q_temp/(v_temp/3))*1000;
                end
            end
            
            %% dynamic data saving as files per app.limit steps
            if length(app.history_data) < app.limit % 这里必须要是小于，要不就会在 T + 1 处保存
                if mod(app.frame_counter, 10) == 0 
                    real_counter = 10;
                    app.history_data{real_counter} = {road, app.frame_counter};
                    app.data_save(app.history_data)
                else
                    real_counter = mod(app.frame_counter, 10);
                end
                app.history_data{real_counter} = {road, app.frame_counter};
            else
                app.history_data = [];

                real_counter = mod(app.frame_counter, 10);
                app.history_data{real_counter} = {road, app.frame_counter};
            end
            
            %% single step visualization 
            pause(0.05)
            app.visualize_road(step, road, app.observation_points,...
                app.max_speed_small, app.emergency_flag);
            
            step = step + 1;

            app.result_struct = struct("num_trials", {num_trials},...
                "counts", {counts},...
                "vehicle_types", {vehicle_types},...
                "vehicles", {vehicles},...
                "road", {road},...
                "num_vehicles", {num_vehicles}, ...
                "total_lanes", {total_lanes}, ...
                "road_length", {road_length}, ...
                "caculate_points", {caculate_points}, ...
                "flow", {flow},...
                "data1_ori", {data1_ori},...
                "num_lanes", {num_lanes},...
                "step", {step});
        end
        
        function simulating(app)
            %% proceed the simulation unless it is called stop
            while 1
                % call the stop 
                if app.pause_flag || app.start_status_flag == true
                    break;
                end
                % single step simulation and visualization
                app.simulator()
            end
        end
        
        function back_play_novel(app)
            % if the slider dose not point to the end frame, then play back with history data
            if app.framesliderSlider.Value < app.framesliderSlider.Limits(2)
                app.framesliderSlider.Value = round(app.framesliderSlider.Value);
                % decide which range the slider points to
                while app.framesliderSlider.Value <= app.framesliderSlider.Limits(2)
                    % data reading and visualization accordingly
                    if ceil(app.framesliderSlider.Value / 10) <= length(app.name_space_temp)
                        file_name_temp = app.name_space_temp{ceil(app.framesliderSlider.Value / 10)};
                        data_raw = load(file_name_temp);
                        
                        data4visual = data_raw.history_data;
                    else
                        data4visual = app.history_data;
                    end
                    
                    % calculate the start frame index
                    start_frame = ceil(mod(app.framesliderSlider.Value, 10));
                    if start_frame == 0
                        start_frame = 10;
                    end
                    if start_frame <= length(data4visual)
                        for i = start_frame : length(data4visual)
                            road_temp = data4visual{i}{1};
                            
                            pause(0.05)
                            app.visualize_road(app.framesliderSlider.Value, road_temp, app.observation_points,...
                                app.max_speed_small, app.emergency_flag);
                            if app.framesliderSlider.Value < app.framesliderSlider.Limits(2)
                                app.framesliderSlider.Value = app.framesliderSlider.Value + 1;
                            end
                        end
                        if app.framesliderSlider.Value == app.framesliderSlider.Limits(2)
                            break
                        end
                    else
                        break
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Start_button
        function Start_buttonPushed(app, event)
            if app.start_status_flag
                app.Start_button.Text = "停止";
                app.start_status_flag = false;
                
                % 如果已经初始化过了，那就不用再初始化了
                if app.framesliderSlider.Value ~= 1
                    app.coefficient_initializer()
                end
                
                app.simulator_initializer();
                
                % 进行单步仿真
                app.simulating();
            else
                app.start_status_flag = true;
                app.Start_button.Text = "开始";

                app.coefficient_initializer()
            end
        end

        % Button pushed function: Puase_button
        function Puase_buttonPushed(app, event)
            if ~(app.pause_flag)
                app.Puase_button.Text = "恢复";
                app.pause_flag = true;
            else
                app.Puase_button.Text = "暂停";
                if app.framesliderSlider.Value == app.framesliderSlider.Limits(2)
                    app.pause_flag = false;
                    app.simulating();
                else
                    app.back_play_novel();
                    app.pause_flag = false;
                    app.simulating();
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {456, 456};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {214, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Start_button = uibutton(app.RightPanel, 'push');
            app.Start_button.ButtonPushedFcn = createCallbackFcn(app, @Start_buttonPushed, true);
            app.Start_button.Text = 'Start';
            app.Puase_button = uibutton(app.RightPanel, 'push');
            app.Puase_button.ButtonPushedFcn = createCallbackFcn(app, @Puase_buttonPushed, true);
            app.Puase_button.Text = 'pause';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'frame slider ';
            app.framesliderSlider = uislider(app.RightPanel);
            app.framesliderSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.framesliderSlider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clean_app_en

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end