classdef clean_app_en2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        Status_Info   matlab.ui.control.TextArea
        Label_4       matlab.ui.control.Label
        Load_Button   matlab.ui.control.Button
        Save_Button   matlab.ui.control.Button
        road1_slope   matlab.ui.control.Spinner
        Label_3       matlab.ui.control.Label
        road2_slope   matlab.ui.control.Spinner
        Label_2       matlab.ui.control.Label
        RightPanel    matlab.ui.container.Panel
        framesSlider  matlab.ui.control.Slider
        Label         matlab.ui.control.Label
        Puase_button  matlab.ui.control.Button
        Start_button  matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        pause_flag = false;
        pause_activate = false;

        history_data = {};
        name_root = './cache_data/CD_'; 
        sim_history_save_dir = './simulation_history/His_';
        
        name_counter = 1;
        history_counter = 1;

        frame_counter = 1;

        slider_major_nums = 6;
        slider_minor_nums = 8;

        limit = 10;

        name_space_temp = {}; 

        max_speed_small = 0;
        emergency_flag = 0;

        observation_points = [];

        pause_activate_back = true;

        result_struct = [];

        road_slope1 = 50;
        road_slope2 = 35;

        history_flag = false; % 是否采用历史数据进行播放
        history_namespace = [];

        start_status_flag = true;
        his_data_len = 1e3;
    end
    
    methods (Access = private)
        function coefficient_initializer(app)
            app.framesSlider.Value = app.frame_counter;
            app.framesSlider.Limits = [0, app.frame_counter];

            app.framesSlider.MajorTicks = [0, app.frame_counter];
            app.framesSlider.MajorTickLabels = {num2str(0), num2str(app.frame_counter)};

            app.framesSlider.MinorTicks = [0, app.frame_counter];

            app.road1_slope.Value = app.road_slope1;
            app.road2_slope.Value = app.road_slope2;

            app.Status_Info.Value = {''};

            cla(app.UIAxes)
            app.UIAxes.Title = [];
            app.UIAxes.XLabel = [];
            app.UIAxes.YLabel = [];

            colorbar(app.UIAxes, 'off')

            app.Start_button.Text = "Start";
            app.Puase_button.Text = "Pause";
        end

        function update_silder(app)
            % 对silder 进行重新更新和计算
            app.framesSlider.Limits = [0, app.frame_counter];
            app.framesSlider.Value = app.frame_counter;
            
            if app.frame_counter < app.slider_major_nums
                app.framesSlider.MajorTicks = linspace(0, app.frame_counter, app.frame_counter);
            else
                app.framesSlider.MajorTicks = linspace(0, app.frame_counter, app.slider_major_nums);
            end

            app.framesSlider.MajorTickLabels = {'a'};
            for i = 1 : length(app.framesSlider.MajorTicks)
                app.framesSlider.MajorTickLabels{i} = num2str(app.framesSlider.MajorTicks(i));
            end
            
            app.framesSlider.MinorTicks = [];
            if length(app.framesSlider.MajorTicks) >= 2
                for i = 2 : length(app.framesSlider.MajorTicks)
                    if app.framesSlider.MajorTicks(i) - app.framesSlider.MajorTicks(i - 1)> app.slider_minor_nums
                        app.framesSlider.MinorTicks = [app.framesSlider.MinorTicks, linspace(app.framesSlider.MajorTicks(i - 1), ...
                            app.framesSlider.MajorTicks(i), app.slider_minor_nums)];
                    else
                        app.framesSlider.MinorTicks = [app.framesSlider.MinorTicks, linspace(app.framesSlider.MajorTicks(i - 1), ...
                            app.framesSlider.MajorTicks(i), app.framesSlider.MajorTicks(i) - app.framesSlider.MajorTicks(i - 1))];
                    end
                end
            end
        end

        function main_simulator(app)
            %% data loading and the initial process

            % load the example data as the system input
            load("Example_Data.mat")
            
            % calculate the data
            flow = data1{:, 2};% traffic flow 2nd column
            speed = data1{:, 3};speed2 = data2{:, 3};speed3 = data3{:, 3};speed4 = data4{:, 3};  % vehicle speed 3rd column
            density = data1{:, 4};density2 = data2{:, 4};density3 = data3{:, 4}; % density 4th column
           
            % data calculation
            speed1_avg = mean(speed);speed2_avg = mean(speed2);speed3_avg = mean(speed3);speed4_avg = mean(speed4);
            density1_avg = mean(density);density2_avg = mean(density2);density3_avg = mean(density3);
            
            %% parameter setting
            road_length = 5000;  % 道路长度 (m)
            num_lanes = 2;  % 普通车道数量
            emergency_lane = 1;  % 应急车道数量
            total_lanes = num_lanes + emergency_lane;  % 总车道数量
            % num_vehicles = 500;  % 车辆数量(实际比这个多多了 难道要全部显示吗)
            simulation_steps = 827;  % 仿真步数(秒数) 对应第一个视频13m47s
            app.max_speed_small = 33;  % 高速限速 (m/s) 120km/h
            max_speed_other = 27; % 高速限速 (m/s) 100km/h
            app.emergency_flag = 0; %默认不启用紧急车道

            app.observation_points = [0, 1000, 2000, 5000];  % 观察点位置 (m)
            caculate_points = [[900, 1100];
                               [1900,2100];
                               [4800,5000]];  % 观察点位置 (m)
            %% initial variable settings
            % vehicle structure entity 
            vehicles = struct('id', {}, 'type', {}, 'length', {}, 'max_speed', {}, 'speed', {}, 'position', {}, 'lane', {});
            
            % vehicle type structure
            vehicle_types = {
                'small', 4, app.max_speed_small;  % 小型汽车
                'medium', 6, max_speed_other;  % 中型车辆
                'large', 8, max_speed_other  % i大型车辆
            };
            
            % road initialization
            road = cell(total_lanes, 1);
            for lane = 1:total_lanes
                road{lane} = [];
            end
            
            % speed unit: m/s
            speed_list = [speed1_avg/3.6, speed2_avg/3.6, speed3_avg/3.6, speed4_avg/3.6];
            
            % put vehicle onto the lane
            num_vehicles_list = [round(density1_avg*1000), round(density2_avg*1000), round(density3_avg*3000)];
            total_num_vehicles = sum(num_vehicles_list);
            num_vehicles = total_num_vehicles;
            count = 1;
            for i = 1:3
                position_range = app.observation_points(i:i+1);
                for j = count:count+num_vehicles_list(i)
                    type_idx = randi(size(vehicle_types, 1));
                    vehicles(j).id = j;
                    vehicles(j).type = vehicle_types{type_idx, 1};
                    vehicles(j).length = vehicle_types{type_idx, 2};
                    vehicles(j).max_speed = vehicle_types{type_idx, 3};
                    vehicles(j).speed = speed_list(i);
                    vehicles(j).position = randi(position_range);
                    vehicles(j).lane = randi(num_lanes);
                    road{vehicles(j).lane} = [road{vehicles(j).lane}, vehicles(j)]; 
                end
                count = count + num_vehicles_list(i);
            end
            
            % lane as the index
            for lane = 1:num_lanes
                % put the vehicle as the 
                [~, idx] = sort([road{lane}.position]);
                road{lane} = road{lane}(idx);
            end
            
            num_trials = 3; % set the sampling times
            counts = zeros(1, num_trials); % initialize the result array
            
            for step = 1:simulation_steps
                if mod(step,3)==0  % generate data per 3 seconds 
                    % dynamically generate a new vehicle according to traffic data
                    num_new_vehicles = round(flow(step/3));
                    remaining_count = num_new_vehicles;
                    for i = 1:num_trials-1
                        counts(i) = randi([0, remaining_count]); 
                        remaining_count = remaining_count - counts(i);
                    end
                    counts(num_trials) = remaining_count;
                else
                    if ~isempty(counts)
                        for i = 1:counts(mod(step,3))
                            type_idx = randi(size(vehicle_types, 1));
                            vehicles(i).id = i+num_vehicles;
                            vehicles(i).type = vehicle_types{type_idx, 1};
                            vehicles(i).length = vehicle_types{type_idx, 2};
                            vehicles(i).max_speed = vehicle_types{type_idx, 3};
                            vehicles(i).speed = data1_ori{:,3}(step)/3.6;
                            vehicles(i).position = randi([0, 100]);
                            vehicles(i).lane = randi(num_lanes);
                            road{vehicles(i).lane} = [road{vehicles(i).lane}, vehicles(i)]; 
                        end
                        num_vehicles = num_vehicles + counts(mod(step,3));
                    end
                end
                avgv = 0; roudouble=0;
                for lane = 1:total_lanes
                    if ~isempty(road{lane})
                        road = update_lane(road{lane}, lane, road, road_length, app.emergency_flag);
                        avgv = (mean([road{1}.speed]) + mean([road{2}.speed]))/2;
                        roudouble = (length(road{1})+length(road{2}))/5000;
                    end
                end

                %% 记录数据,绘制四个观察点的流量和速度图
                data = cell(3, length(app.observation_points));
                for i = 2:length(app.observation_points)
                    if mod(step,3)==0
                        step_temp = 3;
                    else
                        step_temp = mod(step,3);
                    end
                    data{step_temp, i} = record_data(road, caculate_points(i-1,:));
                    q_ori{step,i} = length(data{step_temp, i});
                    v_ori{step,i} = (mean(cell2mat(data{step_temp, i}(:,3))))*3.6;
                    rou_ori{step,i} = (q_ori{step,i}/(v_ori{step,i}))*1000;
                end
                if mod(step,3)==0
                    for j = 2:length(app.observation_points)
                        q_temp = 0; v_temp = 0;
                        for i=1:3
                            if ~isempty(data{i, j})
                                count = length(data{i, j});
                                q_temp = q_temp + count;
                                v_temp = v_temp + mean(cell2mat(data{i, j}(:,3)));
                                data{i,j} = [];
                            end
                        end
                        q{step/3,j} = q_temp;
                        v{step/3,j} = (v_temp/3)*3.6;
                        rou{step/3,j} = (q_temp/(v_temp/3))*1000;
                    end
                end
                
                % 如果已经保存了部分数据，就进行保存
                if length(app.history_data) < app.limit % 这里必须要是小于，要不就会在 T + 1 处保存
                    if mod(app.frame_counter, 10) == 0 
                        real_counter = 10;
                        app.history_data{real_counter} = {road, app.frame_counter};
                        app.data_save(app.history_data)
                    else
                        real_counter = mod(app.frame_counter, 10);
                    end
                    app.history_data{real_counter} = {road, app.frame_counter};
                else
                    app.history_data = [];

                    real_counter = mod(app.frame_counter, 10);
                    app.history_data{real_counter} = {road, app.frame_counter};
                end

                pause(0.05)
                app.visualize_road(step, road, app.observation_points,...
                    app.max_speed_small, app.emergency_flag);
            end
        end
        
        %% 进行单步可视化
        function visualize_road(app, time_step, road, observation_points, max_speed, emergency_flag)
            obs_colors = {'#6F4F67', '#8F302E', '#D27E64', '#5D977B'}; % 观察点颜色图谱
            title(app.UIAxes, ['Time Step: ', int2str(time_step)]); % 图像标题
            xlabel(app.UIAxes, 'X (m)'); % X轴标题
            ylabel(app.UIAxes, 'Y (m)'); % Y轴标题

            degree1 = app.road_slope1; % 路段1斜率
            degree2 = app.road_slope2; % 路段2斜率
        
            if emergency_flag==1
                txt = {'On'};
            else
                txt = {'Down'};
            end
            text(app.UIAxes, 3500,4200,txt);
            
            %% 绘制观察点
            for i = 1:length(observation_points)
                if observation_points(i) <= 2000
                    x1 = observation_points(i) * cosd(degree1);
                    y1 = 0;
        
                    x2 = observation_points(i) * cosd(degree1);
                    y2 = 4500;
                else
                    x1 = 2000 * cosd(degree1) + (observation_points(i) - 2000) * cosd(degree2);
                    y1 = 0;
        
                    x2 = 2000 * cosd(degree1) + (observation_points(i) - 2000) * cosd(degree2);
                    y2 = 4500;
                end
        
                % 使用 patch 函数绘制观察点为宽度15的矩形
                faces = [1, 2, 3, 4];
                vertices = [x1, y1; x1, y2; x2+15, y2; x2+15, y1];
                patch(app.UIAxes, 'faces', faces, 'vertices', vertices, 'EdgeColor', 'None', 'FaceColor', obs_colors{i}, 'FaceAlpha', 0.5);
            end
            
            %% 道路绘制
            for lane = (0:length(road))-0.5
                % 第一段公路绘制
                plot(app.UIAxes, [0, 2000 * cosd(degree1)], -[lane *15 * 6, lane * 15 * 6 + 2000 * sind(degree1)] + [4500 4500], 'k-');
                % 第二段公路绘制
                plot(app.UIAxes, [2000 * cosd(degree1), 2000 * cosd(degree1) + 3000 * cosd(degree2)], ...
                     -[lane * 15 * 6 + 2000 * sind(degree1), lane * 15 * 6 + 2000 * sind(degree1) + 3000 * sind(degree2)] + [4500 4500], 'k-');
            end
            
            %% 车辆绘制数据集中计算
            xs = [];
            ys = [];
            ad = [];
            for lane = 1:length(road)
                for i = 1:length(road{lane})
                    vehicle = road{lane}(i);
                    if vehicle.position <= 2000
                        x = vehicle.position * cosd(degree1);
                        y = 4500-((lane - 1) * 15 * 6 + vehicle.position * sind(degree1));
                    else
                        x = 2000 * cosd(degree1) + (vehicle.position - 2000) * cosd(degree2);
                        y = 4500-((lane - 1) * 15 * 6 + 2000 * sind(degree1) + (vehicle.position - 2000) * sind(degree2));
                    end
                    xs = [xs, x]; % 车辆的X轴信息
                    ys = [ys, y]; % 车辆的Y轴信息
                    % ad = [ad, vehicle.speed/max_speed*num_vehicles]; % 车辆的不透明度信息
                    ad = [ad, 1]; 
                end
            end
            
            %% 车辆绘制
            s = scatter(app.UIAxes, xs, ys, 35, linspace(0, max_speed, length(xs)), "filled",'Marker', "square"); % 将保存好的车辆信息进行集中绘制
            s.AlphaData = ad; % 不透明度设置
            s.MarkerFaceAlpha = 'flat';
        
            h = colorbar(app.UIAxes); % 颜色条生成
            h.TickLabels = linspace(0, max_speed, length(h.TickLabels)); % 颜色条标签修改
            
            axis(app.UIAxes, [-15 3800 0 4600]); % 可视化部分坐标轴限制生成

            % if app.pause_flag
            %     app.pause_simulate;
            %     app.pause_flag = false;
            % end

            if app.framesSlider.Value == app.framesSlider.Limits(2)
                app.update_silder;

                app.frame_counter = app.frame_counter + 1;
            end
        end

        function pause_simulate(app)
            while 1
                pause(0.05)
                if app.pause_activate
                    break
                end
            end

            app.pause_flag = false;
            app.pause_activate = false;
        end
        
        function data_save(app, history_data)
            filename_temp = [app.name_root, num2str(app.name_counter, "%03d"), '.mat'];
            while exist(filename_temp, "file")
                app.name_counter = app.name_counter + 1;
                filename_temp = [app.name_root, num2str(app.name_counter, "%03d"), '.mat'];
            end
            app.name_space_temp{end+1} = filename_temp;
            save(filename_temp, "history_data"); 
        end
        
        function back_play(app)
            % 如果当前的指针指向的不是当前的仿真末尾，那么就再进行绘制
            % app.pause_activate = true;
            app.pause_activate_back = false;
            if app.framesSlider.Value < app.framesSlider.Limits(2)
                % 判断一下在哪个保存的区间
                while app.framesSlider.Value <= app.framesSlider.Limits(2)
                    % 根据当前的指针值，进行数据读取和绘制
                    if ceil(app.framesSlider.Value / 10) <= length(app.name_space_temp)
                        file_name_temp = app.name_space_temp{ceil(app.framesSlider.Value / 10)};
                        data_raw = load(file_name_temp);
                        
                        data4visual = data_raw.history_data;
                    else
                        data4visual = app.history_data;
                    end

                    for i = 1 : length(data4visual)
                        road_temp = data4visual{i}{1};
                    end
                end
            end
            app.pause_activate_back = true;
        end
        
        function simulator_initializer(app)
            %% data loading and the initial process

            % load the example data as the system input
            load("Example_Data.mat")
            
            % calculate the data
            flow = data1{:, 2};% traffic flow 2nd column
            speed = data1{:, 3};speed2 = data2{:, 3};speed3 = data3{:, 3};speed4 = data4{:, 3};  % vehicle speed 3rd column
            density = data1{:, 4};density2 = data2{:, 4};density3 = data3{:, 4}; % density 4th column
           
            % data calculation
            speed1_avg = mean(speed);speed2_avg = mean(speed2);speed3_avg = mean(speed3);speed4_avg = mean(speed4);
            density1_avg = mean(density);density2_avg = mean(density2);density3_avg = mean(density3);
            
            %% parameter setting
            road_length = 5000;  % 道路长度 (m)
            num_lanes = 2;  % 普通车道数量
            emergency_lane = 1;  % 应急车道数量
            total_lanes = num_lanes + emergency_lane;  % 总车道数量
            % num_vehicles = 500;  % 车辆数量(实际比这个多多了 难道要全部显示吗)
            simulation_steps = 827;  % 仿真步数(秒数) 对应第一个视频13m47s
            app.max_speed_small = 33;  % 高速限速 (m/s) 120km/h
            max_speed_other = 27; % 高速限速 (m/s) 100km/h
            app.emergency_flag = 0; %默认不启用紧急车道

            app.observation_points = [0, 1000, 2000, 5000];  % 观察点位置 (m)
            caculate_points = [[900, 1100];
                               [1900,2100];
                               [4800,5000]];  % 观察点位置 (m)
            %% initial variable settings
            % vehicle structure entity 
            vehicles = struct('id', {}, 'type', {}, 'length', {}, 'max_speed', {}, 'speed', {}, 'position', {}, 'lane', {});
            
            % vehicle type structure
            vehicle_types = {
                'small', 4, app.max_speed_small;  % 小型汽车
                'medium', 6, max_speed_other;  % 中型车辆
                'large', 8, max_speed_other  % i大型车辆
            };
            
            % road initialization
            road = cell(total_lanes, 1);
            for lane = 1:total_lanes
                road{lane} = [];
            end
            
            % speed unit: m/s
            speed_list = [speed1_avg/3.6, speed2_avg/3.6, speed3_avg/3.6, speed4_avg/3.6];
            
            % put vehicle onto the lane
            num_vehicles_list = [round(density1_avg*1000), round(density2_avg*1000), round(density3_avg*3000)];
            total_num_vehicles = sum(num_vehicles_list);
            num_vehicles = total_num_vehicles;
            count = 1;
            for i = 1:3
                position_range = app.observation_points(i:i+1);
                for j = count:count+num_vehicles_list(i)
                    type_idx = randi(size(vehicle_types, 1));
                    vehicles(j).id = j;
                    vehicles(j).type = vehicle_types{type_idx, 1};
                    vehicles(j).length = vehicle_types{type_idx, 2};
                    vehicles(j).max_speed = vehicle_types{type_idx, 3};
                    vehicles(j).speed = speed_list(i);
                    vehicles(j).position = randi(position_range);
                    vehicles(j).lane = randi(num_lanes);
                    road{vehicles(j).lane} = [road{vehicles(j).lane}, vehicles(j)]; 
                end
                count = count + num_vehicles_list(i);
            end
            
            % lane as the index
            for lane = 1:num_lanes
                % put the vehicle as the 
                [~, idx] = sort([road{lane}.position]);
                road{lane} = road{lane}(idx);
            end
            
            num_trials = 3; % set the sampling times
            counts = zeros(1, num_trials); % initialize the result array
            
            app.result_struct = struct("num_trials", {num_trials},...
                "counts", {counts},...
                "vehicle_types", {vehicle_types},...
                "vehicles", {vehicles},...
                "road", {road},...
                "num_vehicles", {num_vehicles}, ...
                "total_lanes", {total_lanes}, ...
                "road_length", {road_length}, ...
                "caculate_points", {caculate_points}, ...
                "flow", {flow},...
                "data1_ori", {data1_ori},...
                "num_lanes", {num_lanes},...
                "step", {1});
        end
        
        function simulator(app)
            % 进行单步仿真
            num_trials = app.result_struct.num_trials; counts = app.result_struct.counts;
            vehicle_types = app.result_struct.vehicle_types; vehicles = app.result_struct.vehicles;
            road = app.result_struct.road; num_vehicles = app.result_struct.num_vehicles;
            total_lanes = app.result_struct.total_lanes; road_length = app.result_struct.road_length;
            caculate_points = app.result_struct.caculate_points; step = app.result_struct.step;
            flow = app.result_struct.flow; data1_ori = app.result_struct.data1_ori;
            num_lanes = app.result_struct.num_lanes;

            if mod(step,3)==0  % generate data per 3 seconds 
                % dynamically generate a new vehicle according to traffic data
                num_new_vehicles = round(flow(step/3));
                remaining_count = num_new_vehicles;
                for i = 1:num_trials-1
                    counts(i) = randi([0, remaining_count]); 
                    remaining_count = remaining_count - counts(i);
                end
                counts(num_trials) = remaining_count;
            else
                if ~isempty(counts)
                    for i = 1:counts(mod(step,3))
                        type_idx = randi(size(vehicle_types, 1));
                        vehicles(i).id = i+num_vehicles;
                        vehicles(i).type = vehicle_types{type_idx, 1};
                        vehicles(i).length = vehicle_types{type_idx, 2};
                        vehicles(i).max_speed = vehicle_types{type_idx, 3};
                        vehicles(i).speed = data1_ori{:,3}(step)/3.6;
                        vehicles(i).position = randi([0, 100]);
                        vehicles(i).lane = randi(num_lanes);
                        road{vehicles(i).lane} = [road{vehicles(i).lane}, vehicles(i)]; 
                    end
                    num_vehicles = num_vehicles + counts(mod(step,3));
                end
            end
            avgv = 0; roudouble=0;
            for lane = 1:total_lanes
                if ~isempty(road{lane})
                    road = update_lane(road{lane}, lane, road, road_length, app.emergency_flag);
                    avgv = (mean([road{1}.speed]) + mean([road{2}.speed]))/2;
                    roudouble = (length(road{1})+length(road{2}))/5000;
                end
            end

            %% 记录数据,绘制四个观察点的流量和速度图
            data = cell(3, length(app.observation_points));
            for i = 2:length(app.observation_points)
                if mod(step,3)==0
                    step_temp = 3;
                else
                    step_temp = mod(step,3);
                end
                data{step_temp, i} = record_data(road, caculate_points(i-1,:));
                q_ori{step,i} = length(data{step_temp, i});
                v_ori{step,i} = (mean(cell2mat(data{step_temp, i}(:,3))))*3.6;
                rou_ori{step,i} = (q_ori{step,i}/(v_ori{step,i}))*1000;
            end
            if mod(step,3)==0
                for j = 2:length(app.observation_points)
                    q_temp = 0; v_temp = 0;
                    for i=1:3
                        if ~isempty(data{i, j})
                            count = length(data{i, j});
                            q_temp = q_temp + count;
                            v_temp = v_temp + mean(cell2mat(data{i, j}(:,3)));
                            data{i,j} = [];
                        end
                    end
                    q{step/3,j} = q_temp;
                    v{step/3,j} = (v_temp/3)*3.6;
                    rou{step/3,j} = (q_temp/(v_temp/3))*1000;
                end
            end
            
            %% 数据保存部分
            if length(app.history_data) < app.limit % 这里必须要是小于，要不就会在 T + 1 处保存
                if mod(app.frame_counter, 10) == 0 
                    real_counter = 10;
                    app.history_data{real_counter} = {road, app.frame_counter};
                    app.data_save(app.history_data)
                else
                    real_counter = mod(app.frame_counter, 10);
                end
                app.history_data{real_counter} = {road, app.frame_counter};
            else
                app.history_data = [];

                real_counter = mod(app.frame_counter, 10);
                app.history_data{real_counter} = {road, app.frame_counter};
            end
            
            %% 可视化部分
            pause(0.05)
            app.visualize_road(step, road, app.observation_points,...
                app.max_speed_small, app.emergency_flag);
            
            step = step + 1;

            app.result_struct = struct("num_trials", {num_trials},...
                "counts", {counts},...
                "vehicle_types", {vehicle_types},...
                "vehicles", {vehicles},...
                "road", {road},...
                "num_vehicles", {num_vehicles}, ...
                "total_lanes", {total_lanes}, ...
                "road_length", {road_length}, ...
                "caculate_points", {caculate_points}, ...
                "flow", {flow},...
                "data1_ori", {data1_ori},...
                "num_lanes", {num_lanes},...
                "step", {step});
        end
        
        function status_update(app, temp_info)
            if length(app.Status_Info.Value) < 2 && length(app.Status_Info.Value{1}) < 10
                app.Status_Info.Value{1} = temp_info;
            else
                app.Status_Info.Value{end+1, 1} = temp_info;
            end

            if length(app.Status_Info.Value) > app.his_data_len
                app.Status_Info.Value(1) = [];
            end
        end

        function simulating(app)
            %% 如果不叫停，那么就一直仿真
            while 1
                % 叫停
                if app.pause_flag || app.start_status_flag == true
                    break;
                end
                % 单步仿真与保存
                app.simulator()
            end
        end
        
        function back_play_novel(app)
            % 如果当前的指针指向的不是当前的仿真末尾，那么就再进行绘制
            if app.framesSlider.Value < app.framesSlider.Limits(2)
                app.framesSlider.Value = round(app.framesSlider.Value);
                % 判断一下在哪个保存的区间
                while app.framesSlider.Value <= app.framesSlider.Limits(2)
                    % 根据当前的指针值，进行数据读取和绘制
                    if ceil(app.framesSlider.Value / 10) <= length(app.name_space_temp)
                        file_name_temp = app.name_space_temp{ceil(app.framesSlider.Value / 10)};
                        data_raw = load(file_name_temp);
                        
                        data4visual = data_raw.history_data;
                    else
                        data4visual = app.history_data;
                    end
                    
                    % 这里要算一下起始的那个帧序号
                    start_frame = ceil(mod(app.framesSlider.Value, 10));
                    if start_frame == 0
                        start_frame = 10;
                    end
                    if start_frame <= length(data4visual)
                        for i = start_frame : length(data4visual)
                            road_temp = data4visual{i}{1};
                            
                            pause(0.05)
                            app.visualize_road(app.framesSlider.Value, road_temp, app.observation_points,...
                                app.max_speed_small, app.emergency_flag);
                            if app.framesSlider.Value < app.framesSlider.Limits(2)
                                app.framesSlider.Value = app.framesSlider.Value + 1;
                            end
                        end
                        if app.framesSlider.Value == app.framesSlider.Limits(2)
                            break
                        end
                    else
                        break
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Start_button
        function Start_buttonPushed(app, event)
            if ~(app.history_flag)
                if app.start_status_flag
                    app.start_status_flag = false;

                    if app.framesSlider.Value ~= app.framesSlider.Limits(1) && app.pause_flag == false
                        app.coefficient_initializer();
                        app.simulator_initializer();
                        
                        app.status_update([char(datetime('now')), ': Start button pressed.']);
                        pause(0.01)
                        
                        app.Start_button.Text = "Stop";

                        % 进行单步仿真
                        app.simulating();
                    end
                else
                    app.start_status_flag = true;
    
                    app.coefficient_initializer()

                    app.Start_button.Text = "Start";
                end
            end
        end

        % Button pushed function: Puase_button
        function Puase_buttonPushed(app, event)
            if ~(app.pause_flag)
                app.Puase_button.Text = "Resume";
                app.status_update([char(datetime('now')), ': Pause button pressed.']);
                app.pause_flag = true;
            else
                app.Puase_button.Text = "Pause";
                app.status_update([char(datetime('now')), ': Resume button pressed.']);
                if app.framesSlider.Value == app.framesSlider.Limits(2)
                    app.pause_flag = false;
                    app.simulating();
                else
                    app.back_play_novel();
                    app.pause_flag = false;
                    app.simulating();
                end
            end
        end

        % Value changed function: road2_slope
        function road2_slopeValueChanged(app, event)
            value = app.road2_slope.Value;
            
            app.road_slope1 = value;

            app.status_update([char(datetime('now')), ': 2nd road slope is ', num2str(value) ,'°.']);
        end

        % Value changed function: road1_slope
        function road1_slopeValueChanged(app, event)
            value = app.road1_slope.Value;
            
            app.road_slope2 = value;

            app.status_update([char(datetime('now')), ': 1st road slope is', num2str(value) ,'°.']);
        end

        % Button pushed function: Save_Button
        function Save_ButtonPushed(app, event)
            app.status_update([char(datetime('now')), ': Simulation data is saved.']);
            app.data_save(app.history_data); % 再存一次数据
            sim_history_temp = [app.sim_history_save_dir, num2str(app.history_counter, "%03d"), '.mat'];
            while exist(sim_history_temp, "file")
                app.history_counter = app.history_counter + 1;
                sim_history_temp = [app.sim_history_save_dir, num2str(app.history_counter, "%03d"), '.mat'];
            end
            
            history_data4save = struct("name_space_save", {app.name_space_temp}, ...
                                       "slider_limits", {app.framesSlider.Limits}, ...
                                       "minor_ticks", {app.framesSlider.MinorTicks},...
                                       "major_ticks", {app.framesSlider.MajorTicks}, ...
                                       "major_ticks_lables", {app.framesSlider.MajorTickLabels}, ...
                                       "result_struct", {app.result_struct}, ...
                                       "frame_counter", {app.frame_counter});

            save(sim_history_temp, "history_data4save"); 
        end

        % Button pushed function: Load_Button
        function Load_ButtonPushed(app, event)
            app.history_flag = true;
            app.start_status_flag = false;

            [file,location] = uigetfile('*.mat');
            history_raw = load([location, file]);
            app.name_space_temp = history_raw.history_data4save.name_space_save;

            app.coefficient_initializer();
            
            app.framesSlider.Limits = history_raw.history_data4save.slider_limits;
            app.framesSlider.MinorTicks = history_raw.history_data4save.minor_ticks;
            app.framesSlider.MajorTicks = history_raw.history_data4save.major_ticks;
            app.framesSlider.MajorTickLabels = history_raw.history_data4save.major_ticks_lables;

            app.frame_counter = history_raw.history_data4save.frame_counter;

            app.simulator_initializer();
            app.status_update([char(datetime('now')), ': Simulation data is loaded.']);

            app.back_play_novel();
            app.result_struct = history_raw.history_data4save.result_struct;
            app.simulating();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {462, 462};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'road1 slope';
            app.road2_slope = uispinner(app.LeftPanel);
            app.road2_slope.Limits = [-89 89];
            app.road2_slope.ValueChangedFcn = createCallbackFcn(app, @road2_slopeValueChanged, true);
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'road2 slope';
            app.road1_slope = uispinner(app.LeftPanel);
            app.road1_slope.Limits = [-89 89];
            app.road1_slope.ValueChangedFcn = createCallbackFcn(app, @road1_slopeValueChanged, true);
            app.Save_Button = uibutton(app.LeftPanel, 'push');
            app.Save_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_ButtonPushed, true);
            app.Save_Button.Text = 'Save';
            app.Load_Button = uibutton(app.LeftPanel, 'push');
            app.Load_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_ButtonPushed, true);
            app.Load_Button.Text = 'Load';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = 'logger';
            app.Status_Info = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.Start_button = uibutton(app.RightPanel, 'push');
            app.Start_button.ButtonPushedFcn = createCallbackFcn(app, @Start_buttonPushed, true);
            app.Start_button.Text = 'Start';
            app.Puase_button = uibutton(app.RightPanel, 'push');
            app.Puase_button.ButtonPushedFcn = createCallbackFcn(app, @Puase_buttonPushed, true);
            app.Puase_button.Text = 'Pause';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'frames';
            app.framesSlider = uislider(app.RightPanel);
            app.framesSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.framesSlider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clean_app_en2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end